classdef Atmospheric_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MQ135GaugeLabel             matlab.ui.control.Label
        mqq135                      matlab.ui.control.Gauge
        MQ08GaugeLabel              matlab.ui.control.Label
        mqq8                        matlab.ui.control.Gauge
        MQ07GaugeLabel              matlab.ui.control.Label
        MQ04GaugeLabel              matlab.ui.control.Label
        mqq4                        matlab.ui.control.Gauge
        mqq7                        matlab.ui.control.Gauge
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        Lamp_6                      matlab.ui.control.Lamp
        Lamp_7                      matlab.ui.control.Lamp
        Lamp_8                      matlab.ui.control.Lamp
        EditField                   matlab.ui.control.EditField
        bmp_p                       matlab.ui.control.Gauge
        dht_t                       matlab.ui.control.SemicircularGauge
        dht_h                       matlab.ui.control.SemicircularGauge
        bmp_e                       matlab.ui.control.LinearGauge
        Gauge_2Label                matlab.ui.control.Label
        bmp_t                       matlab.ui.control.SemicircularGauge
        BarometricpressureLabel     matlab.ui.control.Label
        DHTTEMPERATURELabel         matlab.ui.control.Label
        BMPTEMPERATURELabel         matlab.ui.control.Label
        DHTHUMIDITYLabel            matlab.ui.control.Label
        ElevationfromsealevelLabel  matlab.ui.control.Label
        ValuesDropDownLabel         matlab.ui.control.Label
        ValuesDropDown              matlab.ui.control.DropDown
        EntervalueLabel             matlab.ui.control.Label
        EditField2                  matlab.ui.control.EditField
        ComputeButton               matlab.ui.control.Button
        DisplayButton               matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            global val1; global val;
            global mq4; global mq8;global mq7;global mq135;global bmpp;global bmpt;global bmpe;
            global dhtt;global dhth;
            switch val
                case 'MQ-4'
                    %val1=str2num(val1);
                    mq4=(269.7*(val1^2))-(6706.6*val1)+41735
                case 'MQ-7'
                    %val1=str2num(val1);
                    mq7=96.508*(val1^-1.44)
                    val1=0;
                case 'MQ-8'
                    %val1=str2num(val1);
                    mq8=891366*(val1^2) - 297453*val1 + 20427;
                case 'MQ-135'
                    %val1=str2num(val1);
                    mq135=529.38*exp(-1.538*val1);
                case 'BMP Pressure'
                    bmpp=val1;
                case 'DHT Temperature'
                    dhtt=val1;
                case 'BMP Temperature'
                    bmpt=val1;
                case 'DHT Humidity'
                    dhth=val1;
                case 'Elevation from sea level'
                    bmpe=val1;
            end
            
        end

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
           global val1; global val;
            global mq4; global mq8;global mq7;global mq135;global bmpp;global bmpt;global bmpe;
            global dhtt;global dhth;
            app.mqq135.Value=round(mq135)
            app.mqq4.Value=round(mq4);
            app.mqq7.Value=round(mq7);
            app.mqq8.Value=round(mq8);
            app.bmp_p.Value=bmpp;
            app.bmp_t.Value=bmpt;
            app.bmp_e.Value=bmpe;
            app.dht_t.Value=dhtt;
            app.dht_h.Value=dhth;
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            global val1;
            val1=app.EditField2.Value;
            val1=str2num(val1);
        end

        % Value changed function: ValuesDropDown
        function ValuesDropDownValueChanged(app, event)
            global val;
            val = app.ValuesDropDown.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MQ135GaugeLabel = uilabel(app.UIFigure);
            app.MQ135GaugeLabel.Text = 'MQ-135';
            app.mqq135 = uigauge(app.UIFigure, 'circular');
            app.mqq135.Limits = [0 1500];
            app.MQ08GaugeLabel = uilabel(app.UIFigure);
            app.MQ08GaugeLabel.Text = 'MQ-08';
            app.mqq8 = uigauge(app.UIFigure, 'circular');
            app.mqq8.Limits = [0 1500];
            app.MQ07GaugeLabel = uilabel(app.UIFigure);
            app.MQ07GaugeLabel.Text = 'MQ-07';
            app.MQ04GaugeLabel = uilabel(app.UIFigure);
            app.MQ04GaugeLabel.Text = 'MQ-04';
            app.mqq4 = uigauge(app.UIFigure, 'circular');
            app.mqq4.Limits = [0 1500];
            app.mqq7 = uigauge(app.UIFigure, 'circular');
            app.mqq7.Limits = [0 1500];
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'ATMOSPHERIC BOX';
            app.bmp_p = uigauge(app.UIFigure, 'circular');
            app.bmp_p.Limits = [0 1500];
            app.dht_t = uigauge(app.UIFigure, 'semicircular');
            app.dht_t.Limits = [0 80];
            app.dht_h = uigauge(app.UIFigure, 'semicircular');
            app.bmp_e = uigauge(app.UIFigure, 'linear');
            app.bmp_e.Limits = [0 1500];
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = 'Gauge';
            app.bmp_t = uigauge(app.UIFigure, 'semicircular');
            app.bmp_t.Limits = [0 80];
            app.BarometricpressureLabel = uilabel(app.UIFigure);
            app.BarometricpressureLabel.Text = 'Barometric pressure';
            app.DHTTEMPERATURELabel = uilabel(app.UIFigure);
            app.DHTTEMPERATURELabel.Text = 'DHT TEMPERATURE';
            app.BMPTEMPERATURELabel = uilabel(app.UIFigure);
            app.BMPTEMPERATURELabel.Text = 'BMP TEMPERATURE';
            app.DHTHUMIDITYLabel = uilabel(app.UIFigure);
            app.DHTHUMIDITYLabel.Text = 'DHT HUMIDITY';
            app.ElevationfromsealevelLabel = uilabel(app.UIFigure);
            app.ElevationfromsealevelLabel.Text = 'Elevation from sea level';
            app.ValuesDropDownLabel = uilabel(app.UIFigure);
            app.ValuesDropDownLabel.Text = 'Values';
            app.ValuesDropDown = uidropdown(app.UIFigure);
            app.ValuesDropDown.Items = {'', 'MQ-4', 'MQ-7', 'MQ-8', 'MQ-135', 'BMP Pressure', 'DHT Temperature', 'BMP Temperature', 'DHT Humidity', 'Elevation from sea level'};
            app.ValuesDropDown.ValueChangedFcn = createCallbackFcn(app, @ValuesDropDownValueChanged, true);
            app.ValuesDropDown.Value = '';
            app.EntervalueLabel = uilabel(app.UIFigure);
            app.EntervalueLabel.Text = 'Enter value';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Atmospheric_box

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end