classdef MARS_AR_BASED_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Button                    matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        MartianGeologyButton      matlab.ui.control.Button
        PhysicalParametersButton  matlab.ui.control.Button
        ConnecttobotButton        matlab.ui.control.Button
        BiologicalstudyButton     matlab.ui.control.Button
        ExportDataButton          matlab.ui.control.Button
        camera                    matlab.ui.control.Button
        OnLED                     matlab.ui.control.Lamp
        OFFLED                    matlab.ui.control.Lamp
        forward_3                 matlab.ui.control.Button
        status_LED_2              matlab.ui.control.Lamp
        Lamp_3                    matlab.ui.control.Lamp
        piLED                     matlab.ui.control.Lamp
        DM                        matlab.ui.control.EditField
        ArduinoButton             matlab.ui.control.Button
        ads                       matlab.ui.control.EditField
        left                      matlab.ui.control.Button
        rev                       matlab.ui.control.Button
        right                     matlab.ui.control.Button
        forward                   matlab.ui.control.Button
        stop                      matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ConnecttobotButton
        function ConnecttobotButtonPushed(app, event)
            mypi=raspi('192.168.137.5','pi','dpi123dpi');
            global cam;
            global myserialdevice;
            cam=webcam(mypi,1);
            myserialdevice=serialdev(mypi,'/dev/ttyACM0',9600);
            app.piLED.Color=[0,1,0];
            pause(0.2);
            app.piLED.Color=[1,0,0];
            pause(0.2);
            app.piLED.Color=[0,1,0];
            pause(0.2);
            app.piLED.Color=[1,0,0];
            pause(0.2);
            app.piLED.Color=[0,1,0];
            pause(0.2);
            %myserialdevice=serialdev(mypi,'/dev/ttyACM0',115200);
            app.DM.Value='Connection made.';
        end

        % Button pushed function: MartianGeologyButton
        function MartianGeologyButtonPushed(app, event)
            img=imread('Mars_geology2.jpg');
            gray=rgb2gray(img);
            imcontour(gray,[app.Minimum.Value,app.Maximum.Value]);
        end

        % Button pushed function: camera
        function cameraButtonPushed(app, event)
            global cam;
            %disp('Loop in');
            count=0;
            while(count<=10)
                disp('Loop in');
                img=snapshot(cam);
                imshow(img);
                count=count+1;
            end
        end

        % Button pushed function: forward
        function forwardButtonPushed(app, event)
            global myserialdevice;
            write(myserialdevice,'w');
        end

        % Button pushed function: left
        function leftButtonPushed(app, event)
            global myserialdevice;
            write(myserialdevice,'a');
        end

        % Button pushed function: rev
        function revButtonPushed(app, event)
            global myserialdevice;
            write(myserialdevice,'s');
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            global myserialdevice;
            write(myserialdevice,'d');
        end

        % Button pushed function: stop
        function stopButtonPushed(app, event)
            global myserialdevice;
            write(myserialdevice,'x');
        end

        % Button pushed function: forward_3
        function forward_3ButtonPushed(app, event)
            global cam;
            for i=1:10
                img=snapshot(cam);
                r=img(:,:,1);
                g=img(:,:,2);
                b=img(:,:,3);
                pause(0.01);
                img2=snapshot(cam);
                r1=img2(:,:,1);
                g1=img2(:,:,2);
                b1=img2(:,:,3);
                r_dif=abs(r-r1);
                b_dif=abs(b-b1);
                g_dif=abs(g-g1);
                r_F=reshape(r_dif,numel(r_dif),1);
                plot(r_F);
                drawnow
            end
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'MARSAR3.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'MARS AR2.png';
            app.Button_2.Text = '';
            app.MartianGeologyButton = uibutton(app.UIFigure, 'push');
            app.MartianGeologyButton.ButtonPushedFcn = createCallbackFcn(app, @MartianGeologyButtonPushed, true);
            app.MartianGeologyButton.Text = 'Martian Geology';
            app.PhysicalParametersButton = uibutton(app.UIFigure, 'push');
            app.PhysicalParametersButton.Text = 'Physical Parameters';
            app.ConnecttobotButton = uibutton(app.UIFigure, 'push');
            app.ConnecttobotButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttobotButtonPushed, true);
            app.ConnecttobotButton.Text = 'Connect to bot';
            app.BiologicalstudyButton = uibutton(app.UIFigure, 'push');
            app.BiologicalstudyButton.Text = 'Biological study';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.camera = uibutton(app.UIFigure, 'push');
            app.camera.ButtonPushedFcn = createCallbackFcn(app, @cameraButtonPushed, true);
            app.camera.Icon = 'cam_ICONS.jpg';
            app.camera.Text = '';
            app.OnLED = uilamp(app.UIFigure);
            app.OnLED.Enable = 'off';
            app.OFFLED = uilamp(app.UIFigure);
            app.OFFLED.Enable = 'off';
            app.forward_3 = uibutton(app.UIFigure, 'push');
            app.forward_3.ButtonPushedFcn = createCallbackFcn(app, @forward_3ButtonPushed, true);
            app.forward_3.Icon = 'sensor_1.jpg';
            app.forward_3.Text = '';
            app.status_LED_2 = uilamp(app.UIFigure);
            app.status_LED_2.Enable = 'off';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_3.Enable = 'off';
            app.piLED = uilamp(app.UIFigure);
            app.DM = uieditfield(app.UIFigure, 'text');
            app.ArduinoButton = uibutton(app.UIFigure, 'push');
            app.ArduinoButton.Text = 'Arduino';
            app.ads = uieditfield(app.UIFigure, 'text');
            app.ads.Value = '/dev/ttyACM0';
            app.left = uibutton(app.UIFigure, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.left.Icon = 'Navigate_right.png';
            app.left.Text = '';
            app.rev = uibutton(app.UIFigure, 'push');
            app.rev.ButtonPushedFcn = createCallbackFcn(app, @revButtonPushed, true);
            app.rev.Icon = 'navigate_back.png';
            app.rev.Text = '';
            app.right = uibutton(app.UIFigure, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Icon = 'Navigate_left.png';
            app.right.Text = '';
            app.forward = uibutton(app.UIFigure, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forward.Icon = 'navigate_F.png';
            app.forward.Text = '';
            app.stop = uibutton(app.UIFigure, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stop.Icon = 'stop.jpg';
            app.stop.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MARS_AR_BASED_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end