classdef New_MATLAB_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        IPA                matlab.ui.control.EditField
        QuickScanButton    matlab.ui.control.Button
        arduinoport        matlab.ui.control.EditField
        ArduinoReadButton  matlab.ui.control.Button
        L1                 matlab.ui.control.Label
        L2                 matlab.ui.control.Label
        L3                 matlab.ui.control.Label
        L4                 matlab.ui.control.Label
        L5                 matlab.ui.control.Label
        L6                 matlab.ui.control.Label
        Indicator          matlab.ui.control.Switch
        Instruction_field  matlab.ui.control.EditField
        SerialWButton      matlab.ui.control.Button
        MSF                matlab.ui.control.EditField
        fPos               matlab.ui.control.Button
        fNeg               matlab.ui.control.Button
        Val_sent           matlab.ui.control.EditField
        RunCameraButton    matlab.ui.control.Button
        green              matlab.ui.control.Lamp
        red                matlab.ui.control.Lamp
        infra              matlab.ui.control.Lamp
        UV                 matlab.ui.control.Lamp
        G_L                matlab.ui.control.Button
        G_L_2              matlab.ui.control.Button
        G_L_3              matlab.ui.control.Button
        UVs                matlab.ui.control.Button
        OnButton           matlab.ui.control.Button
        OffButton          matlab.ui.control.Button
        Sensor_dataButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: QuickScanButton
        function QuickScanButtonPushed(app, event)
            global mypi;
            global cam;
            cam=0;
            global send_string;
            send_string="";
            global micS;
            global micR;global micQ;
            micQ=2;
            micR=6;
            micS=0;
            mypi=raspi(app.IPA.Value,'pi','dpi123dpi');
            app.Instruction_field.Value='Connected to Rpi! Waiting for Arduino to respond.';
            pause(1);
            app.Instruction_field.Value='First test successful';
            app.Indicator.Value='CNC';
            global myserialdevice;
            global increment;
            increment=0;
            myserialdevice=0;
        end

        % Button pushed function: ArduinoReadButton
        function ArduinoReadButtonPushed(app, event)
            global myserialdevice;
            global mypi;
            if(myserialdevice==0)
                myserialdevice=serialdev(mypi,app.arduinoport.Value,115200);
                app.Instruction_field.Value='Arduino Found!';
            end
            pause(1);
            %temp=read(myserialdevice,70);temp=read(myserialdevice,70);
            count = 0;
            while(count<=20)
                temp=string(extractBetween(char(read(myserialdevice,70)),'(*)','*V*'));
                disp(temp);
                if(~isempty(temp))
                    x=strsplit(temp,',');
                    disp(['x value= ',x]);
                if(length(x)>6)
                    app.L1.Text=x(1);
                    app.L2.Text=x(2);
                    app.L3.Text=x(3);
                    app.L4.Text=x(4);
                    app.L5.Text=x(5);
                    app.L6.Text=x(6);
                end
                end
                app.Instruction_field.Value=temp;
                temp=0;
                %disp(count);
                count=count+1;
            end
        end

        % Button pushed function: SerialWButton
        function SerialWButtonPushed(app, event)
            global myserialdevice;global mypi;global micQ;global micR;global micS;
            if(myserialdevice==0)
               myserialdevice=serialdev(mypi,app.arduinoport.Value,115200);
            end
            writeText=char(strcat(num2str(micQ),",",num2str(micR),",",num2str(micS)))
            write(myserialdevice,writeText);
            %app.TurnD.Value=app.MSF.Value;
        end

        % Button pushed function: fPos
        function fPosPushed(app, event)
            global myserialdevice;global mypi;
            %if (myserialdevice==0)
             %  myserialdevice=serialdev(mypi,app.arduinoport.Value,9600);
            %end
            global micS;
            if(micS<=0)
                micS=micS*(-1)
                micS=micS+5;
            end
            app.MSF.Value=num2str(micS);
        end

        % Button pushed function: fNeg
        function fNegButtonPushed(app, event)
global myserialdevice;global mypi;
            %if (myserialdevice==0)
             %  myserialdevice=serialdev(mypi,app.arduinoport.Value,9600);
            %end
            
            global micS;
                        if(micS<=0)
                micS=micS*(-1)
                micS=micS-5;
                        end
            app.MSF.Value=num2str(micS);
        end

        % Button pushed function: RunCameraButton
        function RunCameraButtonPushed(app, event)
            global mypi;
            %MATLAB BALL DETECTION
            global cam;
            if(cam==0)
            cam=webcam(mypi,1);
            end
            count=0;
    while(count<=100)
        img=snapshot(cam);
            r=img(:,:,1);
                g=img(:,:,2);
                b=img(:,:,3);
                pause(0.01);
                img2=snapshot(cam);
                r1=img2(:,:,1);
                g1=img2(:,:,2);
                b1=img2(:,:,3);
                r_dif=abs(r-r1);
                b_dif=abs(b-b1);
                g_dif=abs(g-g1);
                r_final=reshape(r_dif,numel(r_dif),1);
                g_final=reshape(g_dif,numel(g_dif),1);
                b_final=reshape(b_dif,numel(b_dif),1);
                r_Norm = (r_final - min(r_final))/(max(r_final) - min(r_final));
                g_Norm = (g_final - min(g_final))/(max(g_final) - min(g_final));
                b_Norm = (b_final - min(b_final))/(max(b_final) - min(b_final));
                figure(1);subplot(421);plot(r_final,'r');title('Red variance');xlabel('Pixel Value');ylabel('Difference Level in Pixel');
                grid on;
                subplot(426);plot(g_Norm,'g');title('Green Normalization');xlabel('Pixel Value');ylabel('Difference Level in Pixel');
                grid on;
                subplot(423);plot(b_final,'b');title('Blue variance');xlabel('Pixel Value');ylabel('Difference Level in Pixel');
                grid on;
                subplot(422);plot(r_Norm,'r');title('Red Normalization');xlabel('Pixel Value');ylabel('Difference Level in Pixel');
                grid on;
                subplot(425);plot(g_final,'g');title('Green Variance');xlabel('Pixel Value');ylabel('Difference Level in Pixel');
                grid on;
                subplot(424);plot(b_Norm,'b');title('Blue Normalization');xlabel('Pixel Value');ylabel('Difference Level in Pixel');
                grid on;
                subplot(427);imshow(img);title('First image');
                subplot(428);imshow(img2);title('Image with lag of 0.01s');
                
                drawnow
                count=count+1
    
    end
        end

        % Button pushed function: G_L
        function G_LButtonPushed(app, event)
            global micR;
            micR=2;
            app.green.Enable='on';
            app.red.Enable='off';
            app.infra.Enable='off';
            app.UV.Enable='off';
        end

        % Button pushed function: G_L_2
        function G_L_2ButtonPushed(app, event)
            global micR;
            micR=3;
            app.green.Enable='off';
            app.red.Enable='on';
            app.infra.Enable='off';
            app.UV.Enable='off';
        end

        % Button pushed function: G_L_3
        function G_L_3ButtonPushed(app, event)
                global micR;
            micR=5;
            app.green.Enable='off';
            app.red.Enable='off';
            app.infra.Enable='on';
            app.UV.Enable='off';
        end

        % Button pushed function: UVs
        function UVsButtonPushed(app, event)
            global micR;
            micR=4;
            app.green.Enable='off';
            app.red.Enable='off';
            app.infra.Enable='off';
            app.UV.Enable='on';    
        end

        % Button pushed function: OnButton
        function OnButtonPushed(app, event)
            global micQ;
            micQ=1
        end

        % Button pushed function: OffButton
        function OffButtonPushed(app, event)
            global micQ;
            micQ=2
        end

        % Value changed function: MSF
        function MSFValueChanged(app, event)
            value = app.MSF.Value;
            global micS;
            micS=app.MSF.Value
        end

        % Button pushed function: Sensor_dataButton
        function Sensor_dataButtonPushed(app, event)
            global mypi;
            global myserialdevice;
            if(myserialdevice==0)
                myserialdevice=serialdev(mypi,app.arduinoport.Value,115200);
            end
            
            disp(char(read(myserialdevice,50)));
            pause(0.1);
            disp(char(read(myserialdevice,50)));
            pause(0.1);
            disp(char(read(myserialdevice,50)));
            pause(0.1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'UI_DESIGN_UPDATED_SCIENCE.jpg';
            app.Button.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            app.IPA = uieditfield(app.UIFigure, 'text');
            app.IPA.Value = '192.168.137.5';
            app.QuickScanButton = uibutton(app.UIFigure, 'push');
            app.QuickScanButton.ButtonPushedFcn = createCallbackFcn(app, @QuickScanButtonPushed, true);
            app.QuickScanButton.Text = 'Quick Scan';
            app.arduinoport = uieditfield(app.UIFigure, 'text');
            app.arduinoport.Value = '/dev/ttyACM0';
            app.ArduinoReadButton = uibutton(app.UIFigure, 'push');
            app.ArduinoReadButton.ButtonPushedFcn = createCallbackFcn(app, @ArduinoReadButtonPushed, true);
            app.ArduinoReadButton.Text = 'Arduino Read';
            app.L1 = uilabel(app.UIFigure);
            app.L1.Text = '';
            app.L2 = uilabel(app.UIFigure);
            app.L2.Text = '';
            app.L3 = uilabel(app.UIFigure);
            app.L3.Text = '';
            app.L4 = uilabel(app.UIFigure);
            app.L4.Text = '';
            app.L5 = uilabel(app.UIFigure);
            app.L5.Text = '';
            app.L6 = uilabel(app.UIFigure);
            app.L6.Text = '';
            app.Indicator = uiswitch(app.UIFigure, 'slider');
            app.Indicator.Items = {'DSC', 'CNC'};
            app.Indicator.Value = 'DSC';
            app.Instruction_field = uieditfield(app.UIFigure, 'text');
            app.Instruction_field.Editable = 'off';
            app.SerialWButton = uibutton(app.UIFigure, 'push');
            app.SerialWButton.ButtonPushedFcn = createCallbackFcn(app, @SerialWButtonPushed, true);
            app.SerialWButton.Text = 'Serial W';
            app.MSF = uieditfield(app.UIFigure, 'text');
            app.MSF.ValueChangedFcn = createCallbackFcn(app, @MSFValueChanged, true);
            app.MSF.Value = '200';
            app.fPos = uibutton(app.UIFigure, 'push');
            app.fPos.ButtonPushedFcn = createCallbackFcn(app, @fPosPushed, true);
            app.fPos.Text = 'Focus -ve';
            app.fNeg = uibutton(app.UIFigure, 'push');
            app.fNeg.ButtonPushedFcn = createCallbackFcn(app, @fNegButtonPushed, true);
            app.fNeg.Text = 'Focus +ve';
            app.Val_sent = uieditfield(app.UIFigure, 'text');
            app.RunCameraButton = uibutton(app.UIFigure, 'push');
            app.RunCameraButton.ButtonPushedFcn = createCallbackFcn(app, @RunCameraButtonPushed, true);
            app.RunCameraButton.Text = 'Run Camera';
            app.green = uilamp(app.UIFigure);
            app.green.Enable = 'off';
            app.red = uilamp(app.UIFigure);
            app.red.Enable = 'off';
            app.infra = uilamp(app.UIFigure);
            app.infra.Enable = 'off';
            app.UV = uilamp(app.UIFigure);
            app.UV.Enable = 'off';
            app.G_L = uibutton(app.UIFigure, 'push');
            app.G_L.ButtonPushedFcn = createCallbackFcn(app, @G_LButtonPushed, true);
            app.G_L.Text = 'Green';
            app.G_L_2 = uibutton(app.UIFigure, 'push');
            app.G_L_2.ButtonPushedFcn = createCallbackFcn(app, @G_L_2ButtonPushed, true);
            app.G_L_2.Text = 'Red';
            app.G_L_3 = uibutton(app.UIFigure, 'push');
            app.G_L_3.ButtonPushedFcn = createCallbackFcn(app, @G_L_3ButtonPushed, true);
            app.G_L_3.Text = 'Infrared';
            app.UVs = uibutton(app.UIFigure, 'push');
            app.UVs.ButtonPushedFcn = createCallbackFcn(app, @UVsButtonPushed, true);
            app.UVs.Text = 'UV';
            app.OnButton = uibutton(app.UIFigure, 'push');
            app.OnButton.ButtonPushedFcn = createCallbackFcn(app, @OnButtonPushed, true);
            app.OnButton.Icon = 'exHFan.jpg';
            app.OnButton.Text = 'On';
            app.OffButton = uibutton(app.UIFigure, 'push');
            app.OffButton.ButtonPushedFcn = createCallbackFcn(app, @OffButtonPushed, true);
            app.OffButton.Icon = 'exHFan.jpg';
            app.OffButton.Text = 'Off';
            app.Sensor_dataButton = uibutton(app.UIFigure, 'push');
            app.Sensor_dataButton.ButtonPushedFcn = createCallbackFcn(app, @Sensor_dataButtonPushed, true);
            app.Sensor_dataButton.Text = 'Sensor_data';
        end
    end

    methods (Access = public)

        % Construct app
        function app = New_MATLAB_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end