classdef New_Site_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EvaluateButton      matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Text_display        matlab.ui.control.EditField
        Temp_Show           matlab.ui.control.EditField
        Humid_show          matlab.ui.control.EditField
        Meth_Show           matlab.ui.control.EditField
        Temperature         matlab.ui.control.Lamp
        Humidity            matlab.ui.control.Lamp
        Methane             matlab.ui.control.Lamp
        UIAxes              matlab.ui.control.UIAxes
        blink               matlab.ui.control.Lamp
        Button_3            matlab.ui.control.Button
        SITESELECTIONLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Callback function
        function BeginButtonPushed(app, event)
           global sum;
           sum=[0 0 0 0 0 0 0 0 0 0];
           set(app.ss1,'Enable','On');
           set(app.pm1,'Enable','On');
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            app.blink.Enable='on';
            display_str="Evaluating the site: ";
            temp=[27 28 31 23 29 28 29 28 29 27];
            humid=[15 18 11 13 19 18 19 12 14 17];
            alter=0;
            mq4=[0.22 0.232 0.292 0.213 0.22 0.23 0.21 0.22 0.22 0.23];
            x=[];
            for i=1:10
                app.Text_display.Value=display_str+num2str(i);
                disp(num2str(temp(i)));
                app.Temp_Show.Value=strcat('Temperature data:',num2str(temp(i)),'degrees');
                app.Temperature.Enable='on';
                pause(0.2);
                app.Temperature.Enable='off';
                app.Humid_show.Value=strcat('Humidity data:',num2str(humid(i)),'%');
                app.Humidity.Enable='on';
                pause(0.2);
                app.Humidity.Enable='off';
                app.Meth_Show.Value=strcat('Methane data:',num2str(mq4(i)));
                app.Methane.Enable='on';
                pause(0.2); 
                app.Methane.Enable='off';
                x=[x,temp(i)];
                plot(app.UIAxes,x);
                drawnow
                if(alter==1)
                app.blink.Color='1.00,0.00,0.00';
                alter=0;
                else
                    app.blink.Color='0.00,1.00,0.00';
                    alter=1;
                end
                
                
            end
            app.Text_display.Value='Evaluating...';
            pause(1.5);
            app.Text_display.Value='Algorithmic selected site is Site-4';
        end

        % Callback function
        function NextButtonPushed(app, event)
                    set(app.mst,'Enable','Off');
                    set(app.tmp,'Enable','Off');
                    set(app.mth,'Enable','Off');   
        end

        % Callback function
        function bssPushed(app, event)
            global sum;
            [a1 b1]=max(sum);
            app.bss.Text=num2str(b1);
        end

        % Callback function
        function mstValueChanged(app, event)
            value = app.mst.Value;
            global val; global moist;
            
            switch val
                case 'I'
                    moist(1)=value
                case 'II'
                    moist(2)=value
                case 'III'
                    moist(3)=value;
                case 'IV'
                    moist(4)=value;
                case 'V'
                    moist(5)=value;
                case 'VI'
                    moist(6)=value;
                case 'VII'
                    moist(7)=value;
                case 'VIII'
                    moist(8)=value;
                case 'IX'
                    moist(9)=value;
                case 'X'
                    moist(10)=value;
            end
        end

        % Callback function
        function mthValueChanged(app, event)
            value = app.mth.Value;
            global val;
            global methane;
           
            switch val
                case 'I'
                    methane(1)=value
                case 'II'
                    methane(2)=value
                case 'III'
                    methane(3)=value;
                case 'IV'
                    methane(4)=value;
                case 'V'
                    methane(5)=value;
                case 'VI'
                    methane(6)=value;
                case 'VII'
                    methane(7)=value;
                case 'VIII'
                    methane(8)=value;
                case 'IX'
                    methane(9)=value;
                case 'X'
                    methane(10)=value;
            end
        end

        % Callback function
        function pm1ValueChanged(app, event)
            value = app.pm1.Value;
            switch value
                case 'Moisture'
                    set(app.mst,'Enable','On');
                    set(app.tmp,'Enable','Off');
                    set(app.mth,'Enable','Off');
                case 'Temperature'
                    set(app.mst,'Enable','Off');
                    set(app.tmp,'Enable','On');
                    set(app.mth,'Enable','Off');
                case 'Methane concn'
                    set(app.mst,'Enable','Off');
                    set(app.tmp,'Enable','Off');
                    set(app.mth,'Enable','On');
            end
            
        end

        % Callback function
        function ss1ValueChanged(app, event)
            global val;val = app.ss1.Value;
        end

        % Callback function
        function tmpValueChanged(app, event)
            value = app.tmp.Value;global val;
            global temper;
            
            switch val
                case 'I'
                    temper(1)=value
                case 'II'
                    temper(2)=value
                case 'III'
                    temper(3)=value;
                case 'IV'
                    temper(4)=value;
                case 'V'
                    temper(5)=value;
                case 'VI'
                    temper(6)=value;
                case 'VII'
                    temper(7)=value;
                case 'VIII'
                    temper(8)=value;
                case 'IX'
                    temper(9)=value;
                case 'X'
                    temper(10)=value;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'GUI1.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'gui2.jpg';
            app.Button_2.Text = '';
            app.Text_display = uieditfield(app.UIFigure, 'text');
            app.Temp_Show = uieditfield(app.UIFigure, 'text');
            app.Humid_show = uieditfield(app.UIFigure, 'text');
            app.Meth_Show = uieditfield(app.UIFigure, 'text');
            app.Temperature = uilamp(app.UIFigure);
            app.Temperature.Enable = 'off';
            app.Humidity = uilamp(app.UIFigure);
            app.Humidity.Enable = 'off';
            app.Methane = uilamp(app.UIFigure);
            app.Methane.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            app.blink = uilamp(app.UIFigure);
            app.blink.Enable = 'off';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'logo-01.png';
            app.Button_3.Text = '';
            app.SITESELECTIONLabel = uilabel(app.UIFigure);
            app.SITESELECTIONLabel.Text = 'SITE SELECTION';
        end
    end

    methods (Access = public)

        % Construct app
        function app = New_Site_selection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end