classdef Realtimesiteselection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BeginpreprocessingButton  matlab.ui.control.Button
        Switch                    matlab.ui.control.ToggleSwitch
        ProcessButton             matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        CloseallButton            matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: BeginpreprocessingButton
        function BeginpreprocessingButtonPushed(app, event)
            global vid;
            vid=videoinput('winvideo',1,'YUY2_320X240');
            set(vid,'FramesPerTrigger',Inf);
            set(vid,'ReturnedColorspace','rgb');
            vid.FrameGrabInterval=5; %5 milis
            start(vid);
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            global vid;
            stop(vid);
            flushdata(vid);
            clear all;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
           global vdata;global vid; vdata=100;
            while(vid.FramesAcquired<=round(vdata))
    data=getsnapshot(vid);
    r=data(:,:,1);g=data(:,:,2);b=data(:,:,3);
    %isegm2=r<112&g<83&b<52&(abs(r-g)>18)&(abs(r-g)<30)&(abs(g-b)>13)&(abs(g-b)<30); %%CALIBRATE FOR BLACK SOIL AS WELL
    isegm2 = r>50&g>14&g<175&b<120&(abs(r-g)>=10);
    isegm2=bwareaopen(isegm2,300);
    bw=bwlabel(isegm2,8);
    stats=regionprops(bw,'BoundingBox','Centroid');
    imshow(data);
    hold on;
    for(object=1:length(stats))
        bb=stats(object).BoundingBox;
        bc=stats(object).Centroid;
        rectangle('Position',bb,'Edgecolor','r','LineWidth',2);
        plot(bc(1),bc(2),'-m+');
    end
    hold off;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
             global vdata;
             vdata = app.Slider.Value;
             
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global val;
            val = app.Switch.Value;
            switch val
                case 'On'
                    set(app.Slider,'Enable','On');
                case 'Off'
                    set(app.Slider,'Enable','Off');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BeginpreprocessingButton = uibutton(app.UIFigure, 'push');
            app.BeginpreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @BeginpreprocessingButtonPushed, true);
            app.BeginpreprocessingButton.Text = 'Begin pre processing';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 2000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.CloseallButton = uibutton(app.UIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Realtimesiteselection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end