classdef Science_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        back                           matlab.ui.control.Button
        ConnecttoRaspberryPiButton     matlab.ui.control.Button
        connection                     matlab.ui.control.Lamp
        SoilTemperatureGaugeLabel      matlab.ui.control.Label
        soilT                          matlab.ui.control.Gauge
        SoilHumidityGaugeLabel         matlab.ui.control.Label
        soilH                          matlab.ui.control.Gauge
        SoilMethaneGaugeLabel          matlab.ui.control.Label
        SoilMet                        matlab.ui.control.SemicircularGauge
        AtmosphereMethaneGaugeLabel    matlab.ui.control.Label
        AtmoMet                        matlab.ui.control.SemicircularGauge
        AtmosphereHydrogenGaugeLabel   matlab.ui.control.Label
        AtmoHy                         matlab.ui.control.SemicircularGauge
        AtmosphereCarbonMonoxideLabel  matlab.ui.control.Label
        AtmoCO                         matlab.ui.control.SemicircularGauge
        Browse                         matlab.ui.control.Button
        data                           matlab.ui.control.Lamp
        DisconnectButton               matlab.ui.control.Button
        abort                          matlab.ui.control.Lamp
        IP                             matlab.ui.control.EditField
        AtmosphericDataButton          matlab.ui.control.Button
        CarbonDioxideGaugeLabel        matlab.ui.control.Label
        CO2                            matlab.ui.control.NinetyDegreeGauge
        USBP                           matlab.ui.control.EditField
        USBPort                        matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: ConnecttoRaspberryPiButton
        function ConnecttoRaspberryPiButtonPushed(app, event)
            %clear all
            %close all
            %clc
            app.IP.Enable='off';
            mypi=raspi(app.IP.Value,'pi','dpi123dpi'); %192.168.137.3 or 192.168.43.240
            for i=1:4
            app.connection.Color=[1,0,0];
            pause(0.25);
            app.connection.Color=[0,1,0];
            pause(0.25);
            app.connection.Color=[1,0,0];
            end
            global myserialdevice;
            global temp;
            temp=1;
            myserialdevice=serialdev(mypi,app.USBP.Value,115200);
            app.connection.Color=[0,1,0];
        end

        % Button pushed function: Browse
        function BrowsePushed(app, event)
            global myserialdevice;
            global temp;
            line1=line(nan,nan,'color','blue');
            %line2=line(nan,nan,'color','red');
            count=0;
            for i=1:4
            app.data.Color=[1,0,0];
            pause(0.25);
            app.data.Color=[0,1,0];
            pause(0.25);
            app.data.Color=[1,0,0];
            end
            app.data.Color=[0,1,0];
            tried=0;
            j=0;
            while(temp==1)
                %tempor=char(extractBetween(char(read(myserialdevice,72)),'/*','@#'))
                xmas=char(read(myserialdevice,72));
                xmas=strsplit(xmas,'\n')
                dlmwrite('raw_D.csv',xmas,'delimiter',',','-append');
                %disp(length(char(xmas(2))));
                for i=1:length(xmas)
                if length(char(xmas(i)))==36
                    index=i;
                    tried=1;
                end
                end
                
            %MQ-04 y = 120.93e1.1384x
            %MQ-08 y = 6.294e1.6114x
            %y = 2.6938e1.0867x
            if (tried==1)
                disp(strsplit(char(xmas(index)),','))
            values=strsplit(cell2mat(char(xmas(index))),',')
            if (length(values)<8)
                continue
            end
            app.soilT.Value=int8(str2double(char(values(1))));
            %disp(int16(str2double(char(values(1)))))
            %app.soilT.Value=int16(str2double(char(values(1))));
            app.soilH.Value=int8(str2double(char(values(3))));
            app.SoilMet.Value=int16(120.9*exp(1.1384*str2double(char(values(2)))));
            app.AtmoMet.Value=int16(120.9*exp(1.1384*str2double(char(values(7)))));
            app.AtmoHy.Value=int16(6.294*exp(6.294*str2double(char(values(4)))));
            app.AtmoCO.Value=int16(2.6938*exp(1.0867*str2double(char(values(5)))));
            val=int16(str2double(values(6)));
            app.CO2.Value=int16(str2double(values(8)));
            x1=get(line1,'xData');
            y1=get(line1,'yData');
            %x2=get(line2,'xData');
            %y2=get(line2,'yData');
            x1=[x1 j];
            y1=[y1 val];
            %x2=[x2 i];
            %y2=[y2 val2];
            set(line1,'xData',x1,'yData',y1);
            %set(line2,'xData',x2,'yData',y2);
            final_val=[app.soilT.Value,app.soilH.Value,app.SoilMet.Value,app.AtmoHy.Value,app.AtmoCO.Value,val,app.AtmoMet.Value,app.CO2.Value];
            dlmwrite('sensor_data.csv',final_val,'delimiter',',','-append');
            pause(0.25);
            j=j+1;
            end
            %disp(values);
            %disp(length(values));            
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            global temp;
            temp=2;
            app.IP.Enable='on';
            app.connection.Color=[1,0,0];
            app.data.Color=[1,0,0];
            app.abort.Color=[0,1,0];
            clear all;
            close all;
            clc;
        end

        % Button pushed function: AtmosphericDataButton
        function AtmosphericDataButtonPushed(app, event)
            atmosphere_data
        end

        % Button pushed function: USBPort
        function USBPortButtonPushed(app, event)
            app.USBP.Enable='off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.back = uibutton(app.UIFigure, 'push');
            app.back.Icon = 'kratos.jpg';
            app.back.Text = '';
            app.ConnecttoRaspberryPiButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoRaspberryPiButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoRaspberryPiButtonPushed, true);
            app.ConnecttoRaspberryPiButton.Text = 'Connect to Raspberry Pi';
            app.connection = uilamp(app.UIFigure);
            app.SoilTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.SoilTemperatureGaugeLabel.Text = 'Soil Temperature';
            app.soilT = uigauge(app.UIFigure, 'circular');
            app.soilT.Limits = [0 60];
            app.SoilHumidityGaugeLabel = uilabel(app.UIFigure);
            app.SoilHumidityGaugeLabel.Text = 'Soil Humidity';
            app.soilH = uigauge(app.UIFigure, 'circular');
            app.SoilMethaneGaugeLabel = uilabel(app.UIFigure);
            app.SoilMethaneGaugeLabel.Text = 'Soil Methane';
            app.SoilMet = uigauge(app.UIFigure, 'semicircular');
            app.SoilMet.Limits = [0 300];
            app.SoilMet.Value = 100;
            app.AtmosphereMethaneGaugeLabel = uilabel(app.UIFigure);
            app.AtmosphereMethaneGaugeLabel.Text = 'Atmosphere Methane';
            app.AtmoMet = uigauge(app.UIFigure, 'semicircular');
            app.AtmoMet.Limits = [0 300];
            app.AtmosphereHydrogenGaugeLabel = uilabel(app.UIFigure);
            app.AtmosphereHydrogenGaugeLabel.Text = 'Atmosphere Hydrogen';
            app.AtmoHy = uigauge(app.UIFigure, 'semicircular');
            app.AtmoHy.Limits = [0 200];
            app.AtmoHy.Value = 200;
            app.AtmosphereCarbonMonoxideLabel = uilabel(app.UIFigure);
            app.AtmosphereCarbonMonoxideLabel.Text = {'Atmosphere '; 'Carbon Monoxide'};
            app.AtmoCO = uigauge(app.UIFigure, 'semicircular');
            app.AtmoCO.Limits = [0 250];
            app.Browse = uibutton(app.UIFigure, 'push');
            app.Browse.ButtonPushedFcn = createCallbackFcn(app, @BrowsePushed, true);
            app.Browse.Text = 'Browse Data';
            app.data = uilamp(app.UIFigure);
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.abort = uilamp(app.UIFigure);
            app.IP = uieditfield(app.UIFigure, 'text');
            app.AtmosphericDataButton = uibutton(app.UIFigure, 'push');
            app.AtmosphericDataButton.ButtonPushedFcn = createCallbackFcn(app, @AtmosphericDataButtonPushed, true);
            app.AtmosphericDataButton.Text = 'Atmospheric Data';
            app.CarbonDioxideGaugeLabel = uilabel(app.UIFigure);
            app.CarbonDioxideGaugeLabel.Text = 'Carbon Dioxide';
            app.CO2 = uigauge(app.UIFigure, 'ninetydegree');
            app.CO2.Limits = [800 2500];
            app.CO2.Value = 800;
            app.USBP = uieditfield(app.UIFigure, 'text');
            app.USBP.Value = '/dev/ttyUSB0';
            app.USBPort = uibutton(app.UIFigure, 'push');
            app.USBPort.ButtonPushedFcn = createCallbackFcn(app, @USBPortButtonPushed, true);
            app.USBPort.Text = 'USB PORT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Science_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end