classdef atmosphere_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        bg                              matlab.ui.control.Button
        PressureinhPaLabel              matlab.ui.control.Label
        atm_P                           matlab.ui.control.LinearGauge
        graphT                          matlab.ui.control.Button
        graphH                          matlab.ui.control.Button
        graphP                          matlab.ui.control.Button
        AtmosphericDataLabel            matlab.ui.control.Label
        BrowseDataButton                matlab.ui.control.Button
        TemperatureindegreesGaugeLabel  matlab.ui.control.Label
        atm_T                           matlab.ui.control.Gauge
        HumidityGaugeLabel              matlab.ui.control.Label
        atm_H                           matlab.ui.control.Gauge
    end

    methods (Access = private)

        % Button pushed function: graphT
        function graphTPushed(app, event)
           x=[]
           %figure=figure(1);
            global fileID;
            fileID = fopen('atm_T.txt');
            if fileID>0
while ~feof(fileID)
line=fgetl(fileID)
val=strsplit(line,',');
x=[x,str2double(char(val(1)))]
plot(x,'-ks','LineWidth',2,'MarkerEdgeColor','k','MarkerFaceColor','k','MarkerSize',5)
grid on,title('Atmospheric Temperature Vs Time'),xlabel('Time'),ylabel('Temperature')
pause(0.5);
end
fclose(fileID);
end
        end

        % Button pushed function: graphP
        function graphPButtonPushed(app, event)
            x=[]
            %figure=figure(2);
            global fileID;
            fileID= fopen('atm_T.txt');
            if fileID>0
while ~feof(fileID)
line=fgetl(fileID)
val=strsplit(line,',');
x=[x,str2double(char(val(2)))]
plot(x,'-gs','LineWidth',2,'MarkerEdgeColor','k','MarkerFaceColor','k','MarkerSize',5)
grid on,title('Atmospheric Pressure Vs Time'),xlabel('Time'),ylabel('Pressure(hPa)-->')
pause(0.5);
end
fclose(fileID);
end
        end

        % Button pushed function: graphH
        function graphHButtonPushed(app, event)
            x=[]
            %figure=figure(3);
            global fileID;
            fileID= fopen('atm_T.txt');
            if fileID>0
while ~feof(fileID)
line=fgetl(fileID)
val=strsplit(line,',');
x=[x,str2double(char(val(3)))]
plot(x,'-bs','LineWidth',2,'MarkerEdgeColor','k','MarkerFaceColor','k','MarkerSize',5)
grid on,title('Atmospheric Humidity Vs Time'),xlabel('Time'),ylabel('Humidity')
pause(0.5);
end
fclose(fileID);
end
        end

        % Button pushed function: BrowseDataButton
        function BrowseDataButtonPushed(app, event)
%appending values
x=[];
y=[];
z=[];
fileID = fopen('atm_T.txt');
if fileID>0
while ~feof(fileID)
line=fgetl(fileID)
val=strsplit(line,',');
%val(1)=char(val(1));
x=[x,str2double(char(val(1)))];
y=[y,str2double(char(val(2)))];
z=[z,str2double(char(val(3)))];
%%plot(x,'-gs','LineWidth',2,'MarkerEdgeColor','k','MarkerFaceColor','k','MarkerSize',5)
%grid on,title('Temperature Vs Time'),xlabel('Time'),ylabel('Temperature')
%pause(0.5);
end
fclose(fileID);
disp(x);
disp(y);
disp(z);
end
for i=1:length(x)
    app.atm_T.Value=int8(x(i));
    app.atm_H.Value=int8(z(i));
    app.atm_P.Value=int16(y(i));
    %disp(i);
    pause(0.1);
end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.bg = uibutton(app.UIFigure, 'push');
            app.bg.Icon = 'kratos.3jpg.jpg';
            app.bg.Text = '';
            app.PressureinhPaLabel = uilabel(app.UIFigure);
            app.PressureinhPaLabel.Text = 'Pressure(in hPa)';
            app.atm_P = uigauge(app.UIFigure, 'linear');
            app.atm_P.Limits = [760 1500];
            app.atm_P.Value = 760;
            app.graphT = uibutton(app.UIFigure, 'push');
            app.graphT.ButtonPushedFcn = createCallbackFcn(app, @graphTPushed, true);
            app.graphT.Text = 'Temperature Plot';
            app.graphH = uibutton(app.UIFigure, 'push');
            app.graphH.ButtonPushedFcn = createCallbackFcn(app, @graphHButtonPushed, true);
            app.graphH.Text = 'Humidity Plot';
            app.graphP = uibutton(app.UIFigure, 'push');
            app.graphP.ButtonPushedFcn = createCallbackFcn(app, @graphPButtonPushed, true);
            app.graphP.Text = 'Pressure Plot';
            app.AtmosphericDataLabel = uilabel(app.UIFigure);
            app.AtmosphericDataLabel.Text = 'Atmospheric Data';
            app.BrowseDataButton = uibutton(app.UIFigure, 'push');
            app.BrowseDataButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseDataButton.Text = 'Browse Data';
            app.TemperatureindegreesGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureindegreesGaugeLabel.Text = 'Temperature(in degrees)';
            app.atm_T = uigauge(app.UIFigure, 'circular');
            app.atm_T.Limits = [0 60];
            app.HumidityGaugeLabel = uilabel(app.UIFigure);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.atm_H = uigauge(app.UIFigure, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = atmosphere_data

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end