classdef f1biostratigraphy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BrowseButton       matlab.ui.control.Button
        EditField          matlab.ui.control.EditField
        GaborfilterButton  matlab.ui.control.Button
        FossilReadButton   matlab.ui.control.Button
        drop               matlab.ui.control.DropDown
        dd1                matlab.ui.control.DropDown
        dd2                matlab.ui.control.DropDown
        EqualizeButton     matlab.ui.control.Button
        ValueSliderLabel   matlab.ui.control.Label
        vs                 matlab.ui.control.Slider
        ThreshSliderLabel  matlab.ui.control.Label
        threshslider       matlab.ui.control.Slider
        BeginButton        matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        DeequalizeButton   matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename pathname]=uigetfile('*.*','File Selector');
            fullpathname=strcat(pathname,filename);
            global img; global oimg;
            img=imread(fullpathname);
            imshow(img,'Parent',app.UIAxes);
            oimg=img;
        end

        % Button pushed function: DeequalizeButton
        function DeequalizeButtonPushed(app, event)
            global img; global oimg;
            img=oimg;
            imshow(img,'Parent',app.UIAxes);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.EditField.Text="BIOSTRATIGRAPHY";
            
        end

        % Button pushed function: EqualizeButton
        function EqualizeButtonPushed(app, event)
            global img;
            img=histeq(img);
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: FossilReadButton
        function FossilReadButtonPushed(app, event)
             global img; global img1; global img2; global value1;
            points1=detectSURFFeatures(rgb2gray(img));
            switch value1
                case 'Animal'
                    points2=detectSURFFeatures(rgb2gray(img1))
                    disp('Animal');
                    I2=img1;
                case 'Plant'
                    points2=detectSURFFeatures(rgb2gray(img2))
                    disp('Plant');
                    I2=img2;
            end
            I1=img;
            [feats1,validpts1]=extractFeatures(rgb2gray(I1),points1);
            [feats2,validpts2]=extractFeatures(rgb2gray(I2),points2);
            figure,imshow(I2),hold on,plot(validpts2,'ShowOrientation',true);
            title('Detected features');
            index_pairs=matchFeatures(feats2,feats1,...
                'PreNormalized',true);
            matched_pts1=validpts2(index_pairs(:,1));
            matched_pts2=validpts1(index_pairs(:,2));
            figure,showMatchedFeatures(I2,I1,matched_pts2,matched_pts1,'montage');
            title('Initial Matches');
            
        end

        % Button pushed function: GaborfilterButton
        function GaborfilterButtonPushed(app, event)
            global img;
            I=rgb2gray(img);
                gaborBank = gabor([4 8],[0 90]);
                gaborMag = imgaborfilt(I,gaborBank);
                figure
                subplot(2,2,1);
                for p = 1:4
                    subplot(2,2,p)
                    imshow(gaborMag(:,:,p),[]);
                    theta = gaborBank(p).Orientation;
                    lambda = gaborBank(p).Wavelength;
                    title(sprintf('Orientation=%d, Wavelength=%d',theta,lambda));
                end
        end

        % Value changed function: dd1
        function dd1ValueChanged(app, event)
             value = app.dd1.Value;
             global img1;
             switch value
                 case 'Image-1'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\baltagnostus-eurypyx.jpg');
                 case 'Image-2'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\gerospina-schachti-weeks-trilobite.jpg');
                 case 'Image-3'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\Ichneumonid Wasp Fossil.jpg');
                 case 'Image-4'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\itagnostus-interstrictus-agnostida.jpg');
                 case 'Image-5'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\kootenia.JPG');
                 case 'Image-6'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\modocia-brevispina.jpg');
                 case 'Image-7'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\pseudoarctolepis.jpg');
                 case 'Image-8'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\red-elrathia-kingii-trilobite.jpg');
                 case 'Image-9'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\Shore Bird Track Ichnofossil.jpg');
                 case 'Image-10'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\Trilobites Order Ptychopariida, Family Llanoaspididae-2.jpg');
                 case 'Image-11'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\Trilobites Order Ptychopariida, Family Menomoniidae.jpg');
                 case 'Image-12'
                     img1=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\animal fossils\Weevil Insect Fossil.jpg');
             end
        end

        % Value changed function: dd2
        function dd2ValueChanged(app, event)
            value=app.dd2.Value; global img2;
            switch value
                 case 'PImage-1'
                     img2=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\plant fossils\Beilschmiedia eocenica.jpg');
                 case 'PImage-2'
                     img2=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\plant fossils\Populus willmattae.jpg');
                 case 'PImage-3'
                     img2=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\plant fossils\Pseudosalix handleyi Plant Leaf Fossil.jpg');
                 case 'PImage-4'
                     img2=imread('E:\SAHIL\RW\complete science task\MATLAB GUI\bio stratigraphy fossil database\final fossil map\plant fossils\Salix cockerelli (Willow).jpg');
             end
            
        end

        % Value changed function: drop
        function dropValueChanged(app, event)
            global value1;
            value1 = app.drop.Value
            switch value1
                case 'Animal'
                    set(app.dd2,'Enable','Off');
                    set(app.dd1,'Enable','On');
                case 'Plant'
                    set(app.dd1,'Enable','Off');
                    set(app.dd2,'Enable','On');
            end
        end

        % Value changed function: vs
        function slide2(app, event)
             val=app.vs.Value;
            global img;
            y=double(rgb2gray(img));
            x0=mod(y,2);
            x1=mod(floor(y/2),2);
            x2=mod(floor(y/4),2);
            x3=mod(floor(y/8),2);
            x4=mod(floor(y/16),2);
            x5=mod(floor(y/32),2);
            x6=mod(floor(y/64),2);
            x7=mod(floor(y/128),2);
            switch round(val)
                case 1
                    imshow(x0,'Parent',app.UIAxes);
                case 2
                    imshow(x1,'Parent',app.UIAxes);
                case 3
                    imshow(x2,'Parent',app.UIAxes);
                case 4
                    imshow(x3,'Parent',app.UIAxes);
                case 5
                    imshow(x4,'Parent',app.UIAxes);
                case 6
                    imshow(x5,'Parent',app.UIAxes);
                case 7
                    imshow(x6,'Parent',app.UIAxes);
                case 8
                    imshow(x7,'Parent',app.UIAxes);
            end
        end

        % Value changed function: threshslider
        function thresh(app, event)
            value = app.threshslider.Value;
            global img;a=rgb2gray(img)>round(value);
            imshow(a,'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Editable = 'off';
            app.EditField.Value = 'BIO-STRATIGRAPHY';
            app.GaborfilterButton = uibutton(app.UIFigure, 'push');
            app.GaborfilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaborfilterButtonPushed, true);
            app.GaborfilterButton.Text = 'Gabor filter';
            app.FossilReadButton = uibutton(app.UIFigure, 'push');
            app.FossilReadButton.ButtonPushedFcn = createCallbackFcn(app, @FossilReadButtonPushed, true);
            app.FossilReadButton.Text = 'Fossil Read';
            app.drop = uidropdown(app.UIFigure);
            app.drop.Items = {'Fossil type', 'Animal', 'Plant'};
            app.drop.ValueChangedFcn = createCallbackFcn(app, @dropValueChanged, true);
            app.drop.Value = 'Fossil type';
            app.dd1 = uidropdown(app.UIFigure);
            app.dd1.Items = {'Image-1', 'Image-2', 'Image-3', 'Image-4', 'Image-5', 'Image-6', 'Image-7', 'Image-8', 'Image-9', 'Image-10', 'Image-11', 'Image-12'};
            app.dd1.ValueChangedFcn = createCallbackFcn(app, @dd1ValueChanged, true);
            app.dd1.Value = 'Image-1';
            app.dd2 = uidropdown(app.UIFigure);
            app.dd2.Items = {'PImage-1', 'PImage-2', 'PImage-3', 'PImage-4'};
            app.dd2.ValueChangedFcn = createCallbackFcn(app, @dd2ValueChanged, true);
            app.dd2.Value = 'PImage-1';
            app.EqualizeButton = uibutton(app.UIFigure, 'push');
            app.EqualizeButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizeButtonPushed, true);
            app.EqualizeButton.Text = 'Equalize';
            app.ValueSliderLabel = uilabel(app.UIFigure);
            app.ValueSliderLabel.Text = 'Value';
            app.vs = uislider(app.UIFigure);
            app.vs.Limits = [1 8];
            app.vs.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.vs.ValueChangedFcn = createCallbackFcn(app, @slide2, true);
            app.vs.Value = 1;
            app.ThreshSliderLabel = uilabel(app.UIFigure);
            app.ThreshSliderLabel.Text = 'Thresh';
            app.threshslider = uislider(app.UIFigure);
            app.threshslider.Limits = [0 256];
            app.threshslider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '256'};
            app.threshslider.ValueChangedFcn = createCallbackFcn(app, @thresh, true);
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.Text = 'Begin';
            app.UIAxes = uiaxes(app.UIFigure);
            app.DeequalizeButton = uibutton(app.UIFigure, 'push');
            app.DeequalizeButton.ButtonPushedFcn = createCallbackFcn(app, @DeequalizeButtonPushed, true);
            app.DeequalizeButton.Text = 'De-equalize';
        end
    end

    methods (Access = public)

        % Construct app
        function app = f1biostratigraphy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end