classdef grainandrocksize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        BrowseButton               matlab.ui.control.Button
        Slider                     matlab.ui.control.Slider
        DiscsizeSliderLabel        matlab.ui.control.Label
        DiscsizeSlider             matlab.ui.control.Slider
        BWpixelSliderLabel         matlab.ui.control.Label
        BWpixelSlider              matlab.ui.control.Slider
        BoundImageButton           matlab.ui.control.Button
        OverlayImageButton         matlab.ui.control.Button
        LabelNumberEditFieldLabel  matlab.ui.control.Label
        label2                     matlab.ui.control.NumericEditField
        GeneraterocksizedistributionButton  matlab.ui.control.Button
        GRAINSIZEANALYSISLabel     matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: BWpixelSlider
        function BWpixelSliderValueChanged(app, event)
            global I15;global I6;
            value = app.BWpixelSlider.Value;
            I6=bwareaopen(I15,round(value));
            imshow(I6,'Parent',app.UIAxes);
        end

        % Button pushed function: BoundImageButton
        function BoundImageButtonPushed(app, event)
           global I6;global I7;
           I7=bwperim(I6);
           imshow(I7,'Parent',app.UIAxes);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename pathname]=uigetfile({'*.png'},'File Selector');
            fullpathname=strcat(pathname,filename);
            global img;
            img=imread(fullpathname);
            imshow(img,'Parent',app.UIAxes);
            I11=rgb2gray(img);
            I12=adapthisteq(I11);
            global I13;
            I13=im2bw(I12,graythresh(I11));
        end

        % Value changed function: DiscsizeSlider
        function DiscsizeSliderValueChanged(app, event)
            value = app.DiscsizeSlider.Value
            segm=strel('disk',round(value));
            global I13;
            global I15;
            I15=imopen(I13,segm);
            imshow(I15,'Parent',app.UIAxes);
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            global v1;
            v1 = app.EditField.Value;
            
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            global v2;
            v2 = app.EditField_2.Value;
            
        end

        % Button pushed function: GeneraterocksizedistributionButton
        function GeneraterocksizedistributionButtonPushed(app, event)
          global I6; global Inm;
          %a1=regionprops(I6,'area');
          %a=[1 2 1 2 3 1 1 2 2 3 2 1];
         % bar(a);
         a1=regionprops(I6,'area');
    a2=regionprops(I6,'perimeter');
    a1=struct2array(a1);
    a2=struct2array(a2);
    z=1;z1=1;z2=1;
    j=min(a1);
    k=max(a1);
    %GRAPH FOR AREA
    for i=1:length(a1)
        a1(i)=a1(i)/411163;
    end
    kk=0.000001;
    r=1.335;
    for i=1:length(a1)
        ykI(i)=kk;
        Inm(i)=sum(a1(:)>kk&a1(:)<kk*r);
        kk=kk*r;
    end
    figure1 = figure;
    axes1 = axes('Parent',figure1);
    hold(axes1,'on');
    semilogx(ykI,Inm,'ZDataSource','','DisplayName','grain fineness',...
        'MarkerFaceColor',[1 0 0],...
        'MarkerEdgeColor',[1 0 0],...
        'MarkerSize',8,...
        'Marker','diamond',...
        'LineWidth',3,...
        'Color',[0 0 1]);
    xlabel('Grain size');
    title('Grain Size distribution curve');
    ylabel('Frequenccy of grains present');
    xlim(axes1,[0 0.01]);
    box(axes1,'on');
    set(axes1,'Color',[0.803921580314636 0.878431379795074 0.968627452850342],...
        'FontAngle','italic','FontName','High Tower Text','FontSize',14,...
        'FontWeight','bold','XColor',[0 0 0],'XGrid','on','XMinorTick','on',...
        'XScale','log','YColor',[0 0 0],'YGrid','on','YTick',...
        [0 200 400 600 800 1000 1200 1400],'ZColor',[0 0 0]);
        end

        % Button pushed function: OverlayImageButton
        function OverlayImageButtonPushed(app, event)
            global I6;global I7;
            I8=imoverlay(I6,I7,[1 0 0]);
            imshow(I8,'Parent',app.UIAxes);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            global I6;
            label=bwlabel(I6);
            imshow(label==round(value),'Parent',app.UIAxes);
            app.label2.Value=round(value);
        end

        % Callback function
        function XLimEditFieldValueChanged(app, event)
            global val;
            value = app.XLimEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1000];
            app.Slider.MajorTicks = [0 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DiscsizeSliderLabel = uilabel(app.UIFigure);
            app.DiscsizeSliderLabel.Text = 'Disc size';
            app.DiscsizeSlider = uislider(app.UIFigure);
            app.DiscsizeSlider.Limits = [0 10];
            app.DiscsizeSlider.ValueChangedFcn = createCallbackFcn(app, @DiscsizeSliderValueChanged, true);
            app.BWpixelSliderLabel = uilabel(app.UIFigure);
            app.BWpixelSliderLabel.Text = 'BW pixel';
            app.BWpixelSlider = uislider(app.UIFigure);
            app.BWpixelSlider.Limits = [0 200];
            app.BWpixelSlider.ValueChangedFcn = createCallbackFcn(app, @BWpixelSliderValueChanged, true);
            app.BoundImageButton = uibutton(app.UIFigure, 'push');
            app.BoundImageButton.ButtonPushedFcn = createCallbackFcn(app, @BoundImageButtonPushed, true);
            app.BoundImageButton.Text = 'Bound Image';
            app.OverlayImageButton = uibutton(app.UIFigure, 'push');
            app.OverlayImageButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayImageButtonPushed, true);
            app.OverlayImageButton.Text = 'Overlay Image';
            app.LabelNumberEditFieldLabel = uilabel(app.UIFigure);
            app.LabelNumberEditFieldLabel.Text = 'Label Number';
            app.label2 = uieditfield(app.UIFigure, 'numeric');
            app.label2.Editable = 'off';
            app.GeneraterocksizedistributionButton = uibutton(app.UIFigure, 'push');
            app.GeneraterocksizedistributionButton.ButtonPushedFcn = createCallbackFcn(app, @GeneraterocksizedistributionButtonPushed, true);
            app.GeneraterocksizedistributionButton.Text = 'Generate rock size distribution';
            app.GRAINSIZEANALYSISLabel = uilabel(app.UIFigure);
            app.GRAINSIZEANALYSISLabel.Text = 'GRAIN SIZE ANALYSIS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = grainandrocksize

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end