classdef COLOR_DETECTOR2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        browse                      matlab.ui.control.Button
        ys                          matlab.ui.control.Button
        rs                          matlab.ui.control.Button
        brs                         matlab.ui.control.Button
        bs                          matlab.ui.control.Button
        gs                          matlab.ui.control.Button
        ws                          matlab.ui.control.Button
        ImagebrightnessSliderLabel  matlab.ui.control.Label
        slider                      matlab.ui.control.Slider
        DropDownLabel               matlab.ui.control.Label
        popup                       matlab.ui.control.DropDown
        OrignalimageButton          matlab.ui.control.Button
        DisplayButton               matlab.ui.control.Button
    end


    methods (Access = private)

        % Button pushed function: DisplayButton
        function DisplayButtonPushed(app, event)
            global iyf2;
            imshow(iyf2,'Parent',app.UIAxes);
        end

        % Button pushed function: OrignalimageButton
        function OrignalimageButtonPushed(app, event)
            global img;
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: browse
        function browseButtonPushed(app, event)
            global img;
            [filename pathname]=uigetfile({'*.jpg','*.png'},'File Selector');
            fullpathname=strcat(pathname,filename);
            img=imread(fullpathname);
            global r;global g;global b;
            r=img(:,:,1);g=img(:,:,2);b=img(:,:,3);
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: brs
        function brsButtonPushed(app, event)
             global r;global g;global b;global iy;global ibf; global iy2;
            iy = r>50&g>14&g<175&b<120&(abs(r-g)>=10)&(abs(r-g)<=30);      
            iy1=imcomplement(iy);
            iy2=iy.*iy1;
            ibf=imoverlay(iy2,iy,[1 0.8 0]);
            imshow(ibf,'Parent',app.UIAxes);
        end

        % Button pushed function: bs
        function bsButtonPushed(app, event)
            global r;global g;global b;global iy;global iblf;
            iy = (r<65&g<65&b<65);     
            iy1=imcomplement(iy);
            iy2=iy+iy1;
            iblf=imoverlay(iy2,iy,[0 0 0]);
            imshow(iblf,'Parent',app.UIAxes);
        end

        % Button pushed function: gs
        function gsButtonPushed(app, event)
             global r;global g;global b;global iy;global ig;
            iy = r>50&g>14&g<175&b<120&(abs(r-g)<=10);    
            iy1=imcomplement(iy);
            iy2=iy+iy1;
            ig=imoverlay(iy2,iy,[0 1 0]);
            imshow(ig,'Parent',app.UIAxes);
        end

        % Value changed function: popup
        function popupValueChanged(app, event)
            value = app.popup.Value;
            global iyf; global irf; global ibf;global iblf;global ig;
            global iw;global val;global img; global iyf2;
            switch value
                case 'Yellow soil'
                    %iyf1=rgb2gray(iyf);
                    iyf2=iyf*val;
                    %imshow(iyf2,'Parent',app.UIAxes);
                case 'Yellow soil'
                    %iyf1=rgb2gray(iyf);
                    iyf2=irf*val;
                    %imshow(iyf2,'Parent',app.UIAxes);
                case 'Brown soil'
                    iyf2=ibf*val;
                    %imshow(iyf2,'Parent',app.UIAxes);
                case 'Black soil'
                    iyf2=iblf*val;
                    %imshow(iyf2,'Parent',app.UIAxes);
                case 'Green soil'
                    iyf2=ig*val;
                    %imshow(iyf2,'Parent',app.UIAxes);
                case 'white soil'
                    iyf2=iw*val;
                    %imshow(iyf2,'Parent',app.UIAxes);
                case 'Orignal Image'
                    iyf2= img*val;
                    %imshow(iyf2,'Parent',ap.UIAxes);
              
            end
        end

        % Button pushed function: rs
        function rsButtonPushed(app, event)
            global r;global g;global b;global iy;global irf;
            iy = (r>155&g>50&g<150&b>45&b<130&(abs(g-b)<=10));      
            iy1=imcomplement(iy);
            iy2=iy.*iy1;
            irf=imoverlay(iy2,iy,[1 0 0]);
            imshow(irf,'Parent',app.UIAxes);
        end

        % Value changed function: slider
        function sliderValueChanged(app, event)
            global val;
            val = app.slider.Value;
            
        end

        % Button pushed function: ws
        function wsButtonPushed(app, event)
            global r;global g;global b;global iy;global iw;
            iw = r>200&g>200&b>200;    
            imshow(iw,'Parent',app.UIAxes);
        end

        % Button pushed function: ys
        function ysButtonPushed(app, event)
            global r;global g;global b;global iy;global iyf;
            iy = (r>155&r<240&g>50&g<200&b>45&b<130&(abs(g-b)>54)&(abs(g-b)<75));
            iy1=imcomplement(iy);
            iy2=iy.*iy1;
            iyf=imoverlay(iy2,iy,[1 1 0]);
            imshow(iyf,'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Soil color characteristics')
            app.browse = uibutton(app.UIFigure, 'push');
            app.browse.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browse.Text = 'Browse';
            app.ys = uibutton(app.UIFigure, 'push');
            app.ys.ButtonPushedFcn = createCallbackFcn(app, @ysButtonPushed, true);
            app.ys.Icon = 'ys1.png';
            app.ys.Text = 'Yellow soil';
            app.rs = uibutton(app.UIFigure, 'push');
            app.rs.ButtonPushedFcn = createCallbackFcn(app, @rsButtonPushed, true);
            app.rs.Icon = 'rs1.jpg';
            app.rs.Text = 'Red soil';
            app.brs = uibutton(app.UIFigure, 'push');
            app.brs.ButtonPushedFcn = createCallbackFcn(app, @brsButtonPushed, true);
            app.brs.Icon = 'bs.jpg';
            app.brs.Text = 'Brown soil';
            app.bs = uibutton(app.UIFigure, 'push');
            app.bs.ButtonPushedFcn = createCallbackFcn(app, @bsButtonPushed, true);
            app.bs.Icon = 'bs5.jpg';
            app.bs.Text = 'Black soil';
            app.gs = uibutton(app.UIFigure, 'push');
            app.gs.ButtonPushedFcn = createCallbackFcn(app, @gsButtonPushed, true);
            app.gs.Icon = 'gs2.jpg';
            app.gs.Text = 'Green soil';
            app.ws = uibutton(app.UIFigure, 'push');
            app.ws.ButtonPushedFcn = createCallbackFcn(app, @wsButtonPushed, true);
            app.ws.Icon = 'ws4.jpg';
            app.ws.Text = 'White soil';
            app.ImagebrightnessSliderLabel = uilabel(app.UIFigure);
            app.ImagebrightnessSliderLabel.Text = 'Image brightness';
            app.slider = uislider(app.UIFigure);
            app.slider.Limits = [0 2];
            app.slider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.popup = uidropdown(app.UIFigure);
            app.popup.Items = {'', 'Yellow soil', 'Red soil', 'Brown soil', 'Black soil', 'Green soil', 'White soil', 'Orignal Image'};
            app.popup.ValueChangedFcn = createCallbackFcn(app, @popupValueChanged, true);
            app.popup.Value = '';
            app.OrignalimageButton = uibutton(app.UIFigure, 'push');
            app.OrignalimageButton.ButtonPushedFcn = createCallbackFcn(app, @OrignalimageButtonPushed, true);
            app.OrignalimageButton.Text = 'Orignal image';
            app.DisplayButton = uibutton(app.UIFigure, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayButtonPushed, true);
            app.DisplayButton.Text = 'Display';
        end
    end

    methods (Access = public)

        % Construct app
        function app = COLOR_DETECTOR2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end