classdef colorprocessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BrowseImageButton      matlab.ui.control.Button
        OrignalImageButton     matlab.ui.control.Button
        YellowSoilButton       matlab.ui.control.Button
        RedSoilButton          matlab.ui.control.Button
        GreenSoilButton        matlab.ui.control.Button
        BrownSoilButton        matlab.ui.control.Button
        BlackSoilButton        matlab.ui.control.Button
        WhiteSoilButton        matlab.ui.control.Button
        SOILCOLORPROFILELabel  matlab.ui.control.Label
        GeneratecolordistributionButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: BlackSoilButton
        function BlackSoilButtonPushed(app, event)
             global r;global g;global b;global iy;global iblf;
            iy = (r<65&g<65&b<65);     
            iy1=imcomplement(iy);
            iy2=iy+iy1;
            iblf=imoverlay(iy2,iy,[0 0 0]);
            imshow(iblf,'Parent',app.UIAxes);
        end

        % Button pushed function: BrownSoilButton
        function BrownSoilButtonPushed(app, event)
            global img; global r;global g;global b; global idbr;
            global ibr;
            ibr = r>50&g>14&g<175&b<120&(abs(r-g)>=10)&(abs(g-b)<60);
            ibr2=imcomplement(ibr);
            ibro=ibr.*ibr2;
            idbr=imoverlay(ibro,ibr,[1 0.6571 0]);
            imshow(idbr,'Parent',app.UIAxes);
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [filename pathname]=uigetfile('*.*','File Selector');
            fullfilename=strcat(pathname,filename);
            global img;
            img=imread(fullfilename);
            imshow(img,'Parent',app.UIAxes);
            global r;global g;global b;
            r=img(:,:,1);g=img(:,:,2);b=img(:,:,3);
        end

        % Button pushed function: GeneratecolordistributionButton
        function GeneratecolordistributionButtonPushed(app, event)
            global iw;global iblf;global ig;global irf;global iyf;global idbr;
          i1=imfuse(iw,ig,'blend');
          i2=imfuse(i1,irf,'blend');
          i3=imfuse(i2,iyf,'blend');
          i4=imfuse(i3,idbr,'blend');
          i5=imfuse(i4,iblf,'blend');
          imshow(i5,'Parent',app.UIAxes);
        end

        % Button pushed function: GreenSoilButton
        function GreenSoilButtonPushed(app, event)
            global r;global g;global b;global iy;global ig;
            iy = r>50&g>14&g<175&b<120&(abs(r-g)<=10);    
            iy1=imcomplement(iy);
            iy2=iy.*iy1;
            ig=imoverlay(iy2,iy,[0 1 0]);
            imshow(ig,'Parent',app.UIAxes);
        end

        % Button pushed function: OrignalImageButton
        function OrignalImageButtonPushed(app, event)
            global img;
            imshow(img,'Parent',app.UIAxes);
        end

        % Button pushed function: RedSoilButton
        function RedSoilButtonPushed(app, event)
            global r;global g;global b;global iy;global irf;
            iy = (r>155&g>50&g<150&b>45&b<130&(abs(g-b)<=25));      
            iy1=imcomplement(iy);
            iy2=iy.*iy1;
            irf=imoverlay(iy2,iy,[1 0 0]);
            imshow(irf,'Parent',app.UIAxes);
        end

        % Button pushed function: WhiteSoilButton
        function WhiteSoilButtonPushed(app, event)
             global r;global g;global b;global iy;global iw;
            iw = r>180&g>180&b>180;    
            imshow(iw,'Parent',app.UIAxes);
        end

        % Button pushed function: YellowSoilButton
        function YellowSoilButtonPushed(app, event)
             global r;global g;global b;global iy;global iyf;
            iy= (r>155&r<240&g>50&g<200&b>45&b<130&(abs(g-b)>50)&(abs(g-b)<75));
            iy1=imcomplement(iy);
            iy2=iy.*iy1;
            iyf=imoverlay(iy2,iy,[1 1 0]);
            imshow(iyf,'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.OrignalImageButton = uibutton(app.UIFigure, 'push');
            app.OrignalImageButton.ButtonPushedFcn = createCallbackFcn(app, @OrignalImageButtonPushed, true);
            app.OrignalImageButton.Text = 'Orignal Image';
            app.YellowSoilButton = uibutton(app.UIFigure, 'push');
            app.YellowSoilButton.ButtonPushedFcn = createCallbackFcn(app, @YellowSoilButtonPushed, true);
            app.YellowSoilButton.Icon = 'ys3.jpg';
            app.YellowSoilButton.Text = 'Yellow Soil';
            app.RedSoilButton = uibutton(app.UIFigure, 'push');
            app.RedSoilButton.ButtonPushedFcn = createCallbackFcn(app, @RedSoilButtonPushed, true);
            app.RedSoilButton.Icon = 'rs4.jpg';
            app.RedSoilButton.Text = 'Red Soil';
            app.GreenSoilButton = uibutton(app.UIFigure, 'push');
            app.GreenSoilButton.ButtonPushedFcn = createCallbackFcn(app, @GreenSoilButtonPushed, true);
            app.GreenSoilButton.Icon = 'gs1.jpg';
            app.GreenSoilButton.Text = 'Green Soil';
            app.BrownSoilButton = uibutton(app.UIFigure, 'push');
            app.BrownSoilButton.ButtonPushedFcn = createCallbackFcn(app, @BrownSoilButtonPushed, true);
            app.BrownSoilButton.Icon = '234.jpg';
            app.BrownSoilButton.Text = 'Brown Soil';
            app.BlackSoilButton = uibutton(app.UIFigure, 'push');
            app.BlackSoilButton.ButtonPushedFcn = createCallbackFcn(app, @BlackSoilButtonPushed, true);
            app.BlackSoilButton.Icon = 'bs.jpg';
            app.BlackSoilButton.Text = 'Black Soil';
            app.WhiteSoilButton = uibutton(app.UIFigure, 'push');
            app.WhiteSoilButton.ButtonPushedFcn = createCallbackFcn(app, @WhiteSoilButtonPushed, true);
            app.WhiteSoilButton.Icon = 'ws4.jpg';
            app.WhiteSoilButton.Text = 'White Soil';
            app.SOILCOLORPROFILELabel = uilabel(app.UIFigure);
            app.SOILCOLORPROFILELabel.Text = 'SOIL COLOR PROFILE';
            app.GeneratecolordistributionButton = uibutton(app.UIFigure, 'push');
            app.GeneratecolordistributionButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratecolordistributionButtonPushed, true);
            app.GeneratecolordistributionButton.Text = 'Generate color distribution';
            app.UIAxes = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = colorprocessor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end