classdef sensorbasedsiteselection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SITESELECTIONLabel          matlab.ui.control.Label
        SelectsiteDropDownLabel     matlab.ui.control.Label
        ss1                         matlab.ui.control.DropDown
        ParameterDropDownLabel      matlab.ui.control.Label
        pm1                         matlab.ui.control.DropDown
        MoistureinEditFieldLabel    matlab.ui.control.Label
        mst                         matlab.ui.control.NumericEditField
        TemperatureindegreesEditFieldLabel  matlab.ui.control.Label
        tmp                         matlab.ui.control.NumericEditField
        MethaneinppmEditFieldLabel  matlab.ui.control.Label
        mth                         matlab.ui.control.NumericEditField
        EvaluateButton              matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        SiteLabel                   matlab.ui.control.Label
        SiteLabel_2                 matlab.ui.control.Label
        SiteLabel_3                 matlab.ui.control.Label
        mm                          matlab.ui.control.EditField
        mmet                        matlab.ui.control.EditField
        mw                          matlab.ui.control.EditField
        g                           matlab.ui.control.Lamp
        b                           matlab.ui.control.Lamp
        y                           matlab.ui.control.Lamp
        bss                         matlab.ui.control.Button
        MostmoistLabel              matlab.ui.control.Label
        MostmethanogenicLabel       matlab.ui.control.Label
        MostwarmLabel               matlab.ui.control.Label
        NextButton                  matlab.ui.control.Button
        BeginButton                 matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
           global sum;
           sum=[0 0 0 0 0 0 0 0 0 0];
           set(app.ss1,'Enable','On');
           set(app.pm1,'Enable','On');
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            global temper;global moist;global methane;global sum;
            
            for i=1:10
                [j k]=max(temper);
                [l m]=max(methane);
                [n o]=max(moist);
                sum(k)=sum(k)+6;
                sum(m)=sum(m)+8;
                sum(o)=sum(o)+10;
            end
            app.mm.Value=num2str(o);
            set(app.g,'Enable','On');
            app.mw.Value=num2str(k)
            set(app.b,'Enable','On');
            app.mmet.Value=num2str(m);
            set(app.y,'Enable','On');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
                    set(app.mst,'Enable','Off');
                    set(app.tmp,'Enable','Off');
                    set(app.mth,'Enable','Off');   
        end

        % Button pushed function: bss
        function bssPushed(app, event)
            global sum;
            [a1 b1]=max(sum);
            app.bss.Text=num2str(b1);
        end

        % Value changed function: mst
        function mstValueChanged(app, event)
            value = app.mst.Value;
            global val; global moist;
            
            switch val
                case 'I'
                    moist(1)=value
                case 'II'
                    moist(2)=value
                case 'III'
                    moist(3)=value;
                case 'IV'
                    moist(4)=value;
                case 'V'
                    moist(5)=value;
                case 'VI'
                    moist(6)=value;
                case 'VII'
                    moist(7)=value;
                case 'VIII'
                    moist(8)=value;
                case 'IX'
                    moist(9)=value;
                case 'X'
                    moist(10)=value;
            end
        end

        % Value changed function: mth
        function mthValueChanged(app, event)
            value = app.mth.Value;
            global val;
            global methane;
           
            switch val
                case 'I'
                    methane(1)=value
                case 'II'
                    methane(2)=value
                case 'III'
                    methane(3)=value;
                case 'IV'
                    methane(4)=value;
                case 'V'
                    methane(5)=value;
                case 'VI'
                    methane(6)=value;
                case 'VII'
                    methane(7)=value;
                case 'VIII'
                    methane(8)=value;
                case 'IX'
                    methane(9)=value;
                case 'X'
                    methane(10)=value;
            end
        end

        % Value changed function: pm1
        function pm1ValueChanged(app, event)
            value = app.pm1.Value;
            switch value
                case 'Moisture'
                    set(app.mst,'Enable','On');
                    set(app.tmp,'Enable','Off');
                    set(app.mth,'Enable','Off');
                case 'Temperature'
                    set(app.mst,'Enable','Off');
                    set(app.tmp,'Enable','On');
                    set(app.mth,'Enable','Off');
                case 'Methane concn'
                    set(app.mst,'Enable','Off');
                    set(app.tmp,'Enable','Off');
                    set(app.mth,'Enable','On');
            end
            
        end

        % Value changed function: ss1
        function ss1ValueChanged(app, event)
            global val;val = app.ss1.Value;
        end

        % Value changed function: tmp
        function tmpValueChanged(app, event)
            value = app.tmp.Value;global val;
            global temper;
            
            switch val
                case 'I'
                    temper(1)=value
                case 'II'
                    temper(2)=value
                case 'III'
                    temper(3)=value;
                case 'IV'
                    temper(4)=value;
                case 'V'
                    temper(5)=value;
                case 'VI'
                    temper(6)=value;
                case 'VII'
                    temper(7)=value;
                case 'VIII'
                    temper(8)=value;
                case 'IX'
                    temper(9)=value;
                case 'X'
                    temper(10)=value;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SITESELECTIONLabel = uilabel(app.UIFigure);
            app.SITESELECTIONLabel.Text = 'SITE SELECTION';
            app.SelectsiteDropDownLabel = uilabel(app.UIFigure);
            app.SelectsiteDropDownLabel.Text = 'Select site';
            app.ss1 = uidropdown(app.UIFigure);
            app.ss1.Items = {'', 'I', 'II', 'III', 'IV', 'V', 'VI', 'VII', 'VIII', 'IX', 'X'};
            app.ss1.ValueChangedFcn = createCallbackFcn(app, @ss1ValueChanged, true);
            app.ss1.Enable = 'off';
            app.ss1.Value = '';
            app.ParameterDropDownLabel = uilabel(app.UIFigure);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.pm1 = uidropdown(app.UIFigure);
            app.pm1.Items = {'', 'Moisture', 'Methane concn', 'Temperature'};
            app.pm1.ValueChangedFcn = createCallbackFcn(app, @pm1ValueChanged, true);
            app.pm1.Enable = 'off';
            app.pm1.Value = '';
            app.MoistureinEditFieldLabel = uilabel(app.UIFigure);
            app.MoistureinEditFieldLabel.Text = 'Moisture (in %)';
            app.mst = uieditfield(app.UIFigure, 'numeric');
            app.mst.ValueChangedFcn = createCallbackFcn(app, @mstValueChanged, true);
            app.TemperatureindegreesEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureindegreesEditFieldLabel.Text = 'Temperature (in degrees)';
            app.tmp = uieditfield(app.UIFigure, 'numeric');
            app.tmp.ValueChangedFcn = createCallbackFcn(app, @tmpValueChanged, true);
            app.MethaneinppmEditFieldLabel = uilabel(app.UIFigure);
            app.MethaneinppmEditFieldLabel.Text = 'Methane (in ppm)';
            app.mth = uieditfield(app.UIFigure, 'numeric');
            app.mth.ValueChangedFcn = createCallbackFcn(app, @mthValueChanged, true);
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'GUI1.jpg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'gui2.jpg';
            app.Button_2.Text = '';
            app.SiteLabel = uilabel(app.UIFigure);
            app.SiteLabel.Text = 'Site-';
            app.SiteLabel_2 = uilabel(app.UIFigure);
            app.SiteLabel_2.Text = 'Site-';
            app.SiteLabel_3 = uilabel(app.UIFigure);
            app.SiteLabel_3.Text = 'Site-';
            app.mm = uieditfield(app.UIFigure, 'text');
            app.mm.Editable = 'off';
            app.mmet = uieditfield(app.UIFigure, 'text');
            app.mmet.Editable = 'off';
            app.mw = uieditfield(app.UIFigure, 'text');
            app.mw.Editable = 'off';
            app.g = uilamp(app.UIFigure);
            app.g.Enable = 'off';
            app.b = uilamp(app.UIFigure);
            app.b.Enable = 'off';
            app.y = uilamp(app.UIFigure);
            app.y.Enable = 'off';
            app.bss = uibutton(app.UIFigure, 'push');
            app.bss.ButtonPushedFcn = createCallbackFcn(app, @bssPushed, true);
            app.bss.Text = 'Best site';
            app.MostmoistLabel = uilabel(app.UIFigure);
            app.MostmoistLabel.Text = 'Most moist';
            app.MostmethanogenicLabel = uilabel(app.UIFigure);
            app.MostmethanogenicLabel.Text = 'Most methanogenic';
            app.MostwarmLabel = uilabel(app.UIFigure);
            app.MostwarmLabel.Text = 'Most warm';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
        end
    end

    methods (Access = public)

        % Construct app
        function app = sensorbasedsiteselection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end